classdef MedicalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SaveimageButton  matlab.ui.control.Button
        CropimageButton  matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image
        HorizontalLines
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = app.UIAxes;
            ax.Visible = 'off';
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            % --- Select file ---
            [file, path] = uigetfile({'*.dcm;*.png;*.jpg;*.jpeg;*.tif','Image Files'});
            if isequal(file,0)
                return;
            end
            fullPath = fullfile(path, file);

            % --- Load image ---
            try
                if endsWith(file,'.dcm')
                    img = dicomread(fullPath);
                else
                    img = imread(fullPath);
                end
            catch
                uialert(app.UIFigure,'Could not load image.','Load Error');
                return;
            end

            % If grayscale, skip conversion
            if size(img,3) == 3
                d_gray = rgb2gray(img);
            else
                d_gray = img;
            end

            % --- PRE-PROCESSING ---
            BW     = imbinarize(d_gray);
            BW     = ~BW;
            edges  = edge(BW,'canny',[0.03 0.15]);
            [H,theta,rho] = hough(edges);

            % --- HOUGH: Horizontal Lines ---
            idx90  = abs(theta - 90) < 5;
            idx_90 = abs(theta + 90) < 5;
            idx_horizontal = idx90 | idx_90;

            H_only_horizontal = H;
            H_only_horizontal(:,~idx_horizontal) = 0;

            NumPeaks = 10;
            P_all_horz = houghpeaks(H_only_horizontal, NumPeaks, ...
                'Threshold', 0.2*max(H_only_horizontal(:)), ...
                'NHoodSize',[11 11]);

            lines_all_horz = houghlines(edges, theta, rho, P_all_horz, ...
                'FillGap',70, 'MinLength',100);

            % Save to app object so other buttons can use them
            app.Image = img;
            app.HorizontalLines = lines_all_horz;

            ax = app.UIAxes;
            imshow(img,'Parent', ax); hold(ax,'on');
            ax.DataAspectRatio = [1 1 1];
            ax.XLim = [0 size(img,2)];
            ax.YLim = [0 size(img,1)];
            title(ax,'Selected Horizontal Lines');

            selected_indices = [2, 6];
            selected_colors = [
                1, 0, 1;   % Pink
                0.5, 0, 0.5  % Purple
                ];

            for i = 1:length(selected_indices)
                k = selected_indices(i);
                if k <= length(lines_all_horz)
                    xy = [lines_all_horz(k).point1; lines_all_horz(k).point2];
                    plot(ax, xy(:,1), xy(:,2), ...
                        'Color', selected_colors(i,:), 'LineWidth', 3);
                end
            end
        end

        % Button pushed function: CropimageButton
        function CropimageButtonPushed(app, event)
            % --- Safety: Check original image ---
            if isempty(app.Image)
                uialert(app.UIFigure, 'Load an image first.', 'Error');
                return;
            end

            % --- User crops the image ---
            rect = drawrectangle(app.UIAxes);
            wait(rect);
            cropped = imcrop(app.Image, rect.Position);

            % --- Save cropped image ---
            app.Image = cropped;

            % --- Preprocessing ---
            if size(cropped,3) == 3
                d_gray = rgb2gray(cropped);
            else
                d_gray = cropped;
            end

            BW = imbinarize(d_gray);
            BW = ~BW;
            edges = edge(BW,'canny',[0.03 0.15]);
            [H,theta,rho] = hough(edges);

            % --- Horizontal-line selection ---
            idx90  = abs(theta - 90) < 5;
            idx_90 = abs(theta + 90) < 5;
            idx_horizontal = idx90 | idx_90;

            H_only_horizontal = H;
            H_only_horizontal(:,~idx_horizontal) = 0;

            NumPeaks = 10;
            P_horz = houghpeaks(H_only_horizontal, NumPeaks, ...
                'Threshold', 0.2*max(H_only_horizontal(:)), ...
                'NHoodSize',[11 11]);

            lines_horz = houghlines(edges, theta, rho, P_horz, ...
                'FillGap',70, 'MinLength',100);

            % Save detection results
            app.HorizontalLines = lines_horz;

            % --- Display cropped image with detected horizontal lines ---
            ax = app.UIAxes;
            cla(ax);
            imshow(cropped, 'Parent', ax);
            ax.DataAspectRatio = [1 1 1];
            ax.XLim = [0 size(cropped,2)];
            ax.YLim = [0 size(cropped,1)];
            hold(ax,'on');
            title(ax, 'Cropped Image with Horizontal Lines');

            for k = 1:length(lines_horz)
                xy = [lines_horz(k).point1; lines_horz(k).point2];
                plot(ax, xy(:,1), xy(:,2), 'LineWidth', 2);
            end

            hold(ax,'off');
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if isempty(app.Image)
                uialert(app.UIFigure, 'No cropped image to save.', 'Error');
                return;
            end
        
            [file, path] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Save Cropped Image');
            if isequal(file, 0)
                return;
            end
        
            imwrite(app.Image, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dicom image')
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.CropimageButton = uibutton(app.UIFigure, 'push');
            app.CropimageButton.ButtonPushedFcn = createCallbackFcn(app, @CropimageButtonPushed, true);
            app.CropimageButton.Text = 'Crop image';
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedicalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end